classdef digcom < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        TabGroup             matlab.ui.container.TabGroup
        EmissionTab          matlab.ui.container.Tab
        TabGroup2            matlab.ui.container.TabGroup
        NonoiseTab           matlab.ui.container.Tab
        UIAxes               matlab.ui.control.UIAxes
        UIAxes2              matlab.ui.control.UIAxes
        NoiseTab             matlab.ui.container.Tab
        UIAxes4              matlab.ui.control.UIAxes
        UIAxes3              matlab.ui.control.UIAxes
        ReceptionTab         matlab.ui.container.Tab
        UIAxes5              matlab.ui.control.UIAxes
        UIAxes6              matlab.ui.control.UIAxes
        UIAxes7              matlab.ui.control.UIAxes
        EditField            matlab.ui.control.EditField
        Panel                matlab.ui.container.Panel
        SchemeDropDownLabel  matlab.ui.control.Label
        SchemeDropDown       matlab.ui.control.DropDown
        SNREditFieldLabel    matlab.ui.control.Label
        SNREditField         matlab.ui.control.NumericEditField
        SendPlotButton       matlab.ui.control.Button
        EditField_1          matlab.ui.control.NumericEditField
        EditField_2          matlab.ui.control.NumericEditField
        EditField_3          matlab.ui.control.NumericEditField
        EditField_4          matlab.ui.control.NumericEditField
        EditField_5          matlab.ui.control.NumericEditField
        EditField_6          matlab.ui.control.NumericEditField
        EditField_10         matlab.ui.control.NumericEditField
        EditField_9          matlab.ui.control.NumericEditField
        EditField_8          matlab.ui.control.NumericEditField
        EditField_7          matlab.ui.control.NumericEditField
        CheckBox_1           matlab.ui.control.CheckBox
        CheckBox_2           matlab.ui.control.CheckBox
        CheckBox_3           matlab.ui.control.CheckBox
        CheckBox_4           matlab.ui.control.CheckBox
        CheckBox_5           matlab.ui.control.CheckBox
        CheckBox_6           matlab.ui.control.CheckBox
        CheckBox_7           matlab.ui.control.CheckBox
        CheckBox_8           matlab.ui.control.CheckBox
        EditField_11         matlab.ui.control.EditField
        RandomCheckBox       matlab.ui.control.CheckBox
        CheckBox_9           matlab.ui.control.CheckBox
        CheckBox_10          matlab.ui.control.CheckBox
        EditField_12         matlab.ui.control.NumericEditField
        EditField_13         matlab.ui.control.NumericEditField
        EditField_14         matlab.ui.control.NumericEditField
        EditField_15         matlab.ui.control.NumericEditField
        EditField_16         matlab.ui.control.NumericEditField
        EditField_17         matlab.ui.control.NumericEditField
        EditField_18         matlab.ui.control.NumericEditField
        EditField_19         matlab.ui.control.NumericEditField
        EditField_20         matlab.ui.control.NumericEditField
        EditField_21         matlab.ui.control.NumericEditField
        EditField_22         matlab.ui.control.EditField
        BEREditFieldLabel    matlab.ui.control.Label
        BEREditField         matlab.ui.control.NumericEditField
    end


    properties (Access = private)
        N  %nombre totale de bits
        V  % l'Amplitude de
        Tb % durée de bit
        n  % nombre de bit par symbole
        Ts % durée de symbole
        Y
        SNR
        X
        t
        M % dictionnaire du codage
        h
        e
        e_b
        Eth
        b
        Dict_s
        scheme
        Ebit
        r
    end

    methods (Access = private)
    
        function h = shape_gen(app,V,t,scheme)
            if strcmp(scheme,'NRZ')
                h(1:length(t))=V;
            elseif strcmp(scheme,'Manchester')
                h(1:floor(length(t)/2))=V;
                h(floor(length(t)/2)+1:length(t))=-V;
            elseif strcmp(scheme,'RZ')
                h(1:floor(length(t)/2))=V;
                h(floor(length(t)/2)+1:length(t))=0;
            end
        end
        function Y = imp_gen(app,X,N,scheme)
            Y=zeros(1,N);
            if strcmp(scheme,'NRZ')
                for i=1:N
                if (X(i)==0)
                    Y(i)=-1;
                else
                    Y(i)=1;
                end
                end
            elseif strcmp(scheme,'AMI')
                temp=-1;
                for i=1:N
                        if(X(i)==0)
                        Y(i)=0;
                        else
                            if( temp==1)
                            Y(i)=-1;
                            temp=-1;
                            else
                            Y(i)=1;
                            temp=1;
                            end
                        end
                 
                end
            elseif strcmp(scheme,'Manchester')
                for i=1:N
                    if (X(i)==0)
                        Y(i)=-1;
                    else 
                        Y(i)=1;
                    end
                end
            elseif strcmp(scheme,'MLT3')
                temp=1;
                for i=1:N
                    if(i==1)
                        if(X(i)==0)
                            Y(i)=0;
                        else
                            Y(i)=1;
                        end
                    else
                        if(X(i)==1)
                            if(Y(i-1)==1)
                                Y(i)=0;
                            elseif (Y(i-1)==0 && temp==1)
                                Y(i)=-1;
                                temp=-1;
                            elseif( Y(i-1)==0 && temp==-1)
                                Y(i)=1;
                                temp=1;
                            end
                        else
                            Y(i)=Y(i-1);
                        end
                    end
                end
            elseif strcmp(scheme,'RZ')
                for i=1:N
                    if (X(i)==0)
                        Y(i)=0;
                    else 
                        Y(i)=1;
                    end
                end
            elseif strcmp(scheme,'2B1Q')
                m=1;
                for i=1:2:N
                    if(X(i)==0 && X(i+1)==0)
                        Y(m)=-3;
                    elseif(X(i)==0 && X(i+1)==1)
                        Y(m)=-1;
                    elseif(X(i)==1 && X(i+1)==0)
                        Y(m)=1;
                    else
                        Y(m)=3;
                    end
                    m=m+1;
                end
            end
        end

        function D = Dict(app,N,h)
            D=0;
            for i=1:N
                D=D+(h(i))^2;
            end
        end
        function E = E_formant(app,N,h)
            E=0;
            for i= 1:N
                E = E+(h(i))^2;
            end
        end
        
        function Ebit = Ebit_average(app,N,D,E)
            Ebit=1/N*D*E;
        end
        function Z = echant(app,r,scheme)
            j=1
            if strcmp(scheme,'RZ')
                v_temp=1;
                for i=1:length(r)
                    if (mod(i,101)==0)
                        Z(j)=r(floor((i+v_temp)/2));
                        j=j+1;
                        v_temp=i;
                    end
                    
                end
            else
                for i=1:length(r)
                    if (mod(i,101)==0)
                        Z(j)=r(i);
                        j=j+1;
                    end
                end
            end
        end
        function r = filtre_recep(app,e,h,scheme,V)
            if strcmp(scheme,'Miller')
                h(1:length(app.t))=V;
            end
            r_prime=conv2(e,h);
            r=r_prime/500;
        end
        function a = bits_recep(app,z,N,scheme)
            if strcmp(scheme,'NRZ')
                H = 1;
                L=0;
                threshold=1.5   
            elseif strcmp(scheme,'AMI')
                H=1;
                L=0;
                threshold=2;
                a=zeros(1,N);
                for i=1:length(z)
                    if(abs(z(i))>= threshold)
                        a(i)=H;
                    else
                        a(i)=L;
                    end
                end
                return
            elseif strcmp(scheme,'Manchester')
                H=0;
                L=1;
                
            elseif strcmp(scheme,'Miller')
                threshold=1;
                H=1;
                L=0;
                a=zeros(1,N);
                for i=1:length(z)
                    if(z(i)<=threshold && z(i)>=threshold)
                        a(i)=H;
                    elseif (z(i)>threshold && z(i)<threshold)
                        a(i)=L;
                    end
                end
                return
            elseif strcmp(scheme,'MLT3')
                H=1;
                L=0;
                threshold=2.5;
                a=zeros(1,N);
                for i=1:length(z)
                    if(i==1)
                        if(z(i)<threshold) 
                            a(i)=L;
                        else
                            a(i)=H;
                        end
                    else
                        if(abs(z(i)-z(i-1))>threshold)
                            a(i)=H;
                        else
                            a(i)=L;
                        end
                    end
                end
                return
            elseif strcmp(scheme,'RZ')
                H=1;
                L=0;
                threshold=1.5;
                a=zeros(1,N);
                for i=1:length(z)
                    if(z(i)>= threshold)
                        a(i)=H;
                    else
                        a(i)=L;
                    end
                end
                return
            elseif strcmp(scheme,'2B1Q')
                H=1;
                L=0;
                threshold=2.5;
                a=zeros(1,N);
                n=1;
                for i=1:length(z)
                    if(z(i)<=-10)
                        a(n)=L;
                        a(n+1)=L;
                    elseif(z(i)>-threshold*4 && z(i)<= -threshold)
                        a(n)=L;
                        a(n+1)=H;
                    elseif(z(i)>threshold && z(i)<threshold*4)
                        a(n)=H;
                        a(n+1)=L;
                    elseif( z(i)>=threshold*4)
                        a(n)=H;
                        a(n+1)=H;
                    else
                       a(n)=H;
                       a(n+1)=H; 
                    end
                    n=n+2;
                end
                return
            end
            
            a=zeros(1,N);
            for i=1:length(z)
                threshold=2;
                if(z(i)>= threshold)
                    a(i)=H;
                elseif (z(i)<=-threshold)
                    a(i)=L;
                end
            end
        end
        function dk=decodage(app,ak,N)
            p=1;
            for i=1:length(ak)
                if(mod(i,N)==0) 
                        str=num2str(ak((i+1)-N:i));
                        dk(p)=bin2dec(str);
                        p=p+1;
                end
            end
        end
        function updateValues(app,SNR,new_SNR)
                SNR=new_SNR;
        end
        function BER = BER_calc(app,ak,N,X)
            compteur_erreur=0;
            for i=1:length(ak)
                if( ak(i)~=X(i))
                    compteur_erreur=compteur_erreur+1;
                end   
            end
            BER =compteur_erreur/N;
        end
    end


    methods (Access = private)

        % Callback function
        function CalculateButtonPushed(app, event)
             
        end

        % Value changed function: CheckBox_10
        function CheckBox_10ValueChanged(app, event)
            value = app.CheckBox_10.Value;
            app.EditField_10.Value=double(value);
        end

        % Value changed function: CheckBox_1
        function CheckBox_1ValueChanged(app, event)
            value = app.CheckBox_1.Value;
            app.EditField_1.Value=double(value);
        end

        % Value changed function: CheckBox_2
        function CheckBox_2ValueChanged(app, event)
            value = app.CheckBox_2.Value;
            app.EditField_2.Value=double(value);
        end

        % Value changed function: CheckBox_3
        function CheckBox_3ValueChanged(app, event)
            value = app.CheckBox_3.Value;
            app.EditField_3.Value=double(value);
        end

        % Value changed function: CheckBox_4
        function CheckBox_4ValueChanged(app, event)
            value = app.CheckBox_4.Value;
            app.EditField_4.Value=double(value);
        end

        % Value changed function: CheckBox_5
        function CheckBox_5ValueChanged(app, event)
            value = app.CheckBox_5.Value;
            app.EditField_5.Value=double(value);
        end

        % Value changed function: CheckBox_6
        function CheckBox_6ValueChanged(app, event)
            value = app.CheckBox_6.Value;
            app.EditField_6.Value=double(value);
        end

        % Value changed function: CheckBox_7
        function CheckBox_7ValueChanged(app, event)
            value = app.CheckBox_7.Value;
            app.EditField_7.Value=double(value);
        end

        % Value changed function: CheckBox_8
        function CheckBox_8ValueChanged(app, event)
            value = app.CheckBox_8.Value;
            app.EditField_8.Value=double(value);
        end

        % Value changed function: CheckBox_9
        function CheckBox_9ValueChanged(app, event)
            value = app.CheckBox_9.Value;
            app.EditField_9.Value=double(value);
        end

        % Value changed function: EditField_1
        function EditField_1ValueChanged(app, event)
            
        end

        % Callback function
        function InitButtonPushed(app, event)
            
        end

        % Value changed function: SchemeDropDown
        function SchemeDropDownValueChanged(app, event)
            
            
        end

        % Button pushed function: SendPlotButton
        function SendPlotButtonPushed(app, event)
            
            app.N=10;
            app.V=5; % l'Amplitude de
            app.Tb=0.5; % durée de bit
            app.n=2; % nombre de bit par symbole
            app.Ts=app.n*app.Tb; % durée de symbole
            fs=100;
            order=32;
            f=0:0.01:fs;
            app.Y=zeros(1,app.N);
            app.SNR = app.SNREditField.Value;
            app.t= (0:1/fs:app.Ts);
            t_=(0:1/fs:10*app.Ts);
            length_t=length(t_);
            app.X=zeros(1,app.N);
            if app.RandomCheckBox.Value==1
                app.X = randi([0,1],1,app.N);
            else
                app.X(1)=app.CheckBox_1.Value;
                app.X(2)=app.CheckBox_2.Value;
                app.X(3)=app.CheckBox_3.Value;
                app.X(4)=app.CheckBox_4.Value;
                app.X(5)=app.CheckBox_5.Value;
                app.X(6)=app.CheckBox_6.Value;
                app.X(7)=app.CheckBox_7.Value;
                app.X(8)=app.CheckBox_8.Value;
                app.X(9)=app.CheckBox_9.Value;
                app.X(10)=app.CheckBox_10.Value;
                
                app.EditField_1.Value=double(app.CheckBox_1.Value);
                app.EditField_2.Value=double(app.CheckBox_2.Value);
                app.EditField_3.Value=double(app.CheckBox_3.Value);
                app.EditField_4.Value=double(app.CheckBox_4.Value);
                app.EditField_5.Value=double(app.CheckBox_5.Value);
                app.EditField_6.Value=double(app.CheckBox_6.Value);
                app.EditField_7.Value=double(app.CheckBox_7.Value);
                app.EditField_8.Value=double(app.CheckBox_8.Value);
                app.EditField_9.Value=double(app.CheckBox_9.Value);
                app.EditField_10.Value=double(app.CheckBox_10.Value);
            end
            %app.X = [ 1 1 1 0 0 1 0 1 1 1];
            value = app.SchemeDropDown.Value;
            %app.updateValues(app.SNR,)
            if strcmp(value,'None')
                return
            elseif strcmp(value,'NRZ')
                app.scheme='NRZ';
                app.M=[-1 1];
                app.h = app.shape_gen(app.V,app.t,app.scheme);
                app.Y = app.imp_gen(app.X,app.N,app.scheme);
                app.scheme='NRZ';
            elseif strcmp(value,'AMI')
                app.scheme='AMI';
                app.M= [ -1 0 1];
                app.h = app.shape_gen(app.V,app.t,'NRZ');
                app.Y = app.imp_gen(app.X,app.N,app.scheme);
            elseif strcmp(value,'Manchester')
                app.scheme='Manchester';
                app.M = [ -1 1];
                app.h = app.shape_gen(app.V,app.t,app.scheme);
                app.Y= app.imp_gen(app.X,app.N,app.scheme);
            elseif strcmp(value,'Miller')
                app.scheme='Miller';
                app.M=[-1 1];
            elseif strcmp(value,'MLT3')
                app.scheme='MLT3';
                app.M=[-1 1];
                app.h = app.shape_gen(app.V,app.t,'NRZ');
                app.Y= app.imp_gen(app.X,app.N,app.scheme);
            elseif strcmp(value,'RZ')
                app.scheme='RZ';
                app.M= [0 1 ];
                app.h = app.shape_gen(app.V,app.t,app.scheme);
                app.Y= app.imp_gen(app.X,app.N,app.scheme);
            elseif strcmp(value,'2B1Q')
                app.scheme='2B1Q';
                app.M= [ -3 -1 1 3];
                app.h = app.shape_gen(app.V,app.t,'NRZ');
                app.Y= app.imp_gen(app.X,app.N,app.scheme);
            end


            if strcmp(app.scheme,'Miller')
                app.e=[];
                for i=1:app.N
                    if( i==1 ) 
                        if( app.X(i)==0)
                            h_MIL(1:length(app.t))=app.V;
                            app.Y(i)=1;
                            
                        else
                            h_MIL(1:floor(length(app.t)/2))=app.V;
                            h_MIL(floor(length(app.t)/2)+1:length(app.t))=-app.V;
                            app.Y(i)=1;
                        end
                    else
                        if(app.X(i)==0)
                            h_MIL(1:length(app.t))=app.V;
                            if(app.X(i-1)==0)
                                if(app.Y(i-1)==1)
                                    app.Y(i)=-1;
                                else
                                    app.Y(i)=1;
                                end
                            else
                                if(app.Y(i-1)==-1)
                                    app.Y(i)=1;
                                else
                                    app.Y(i)=-1;
                                end
                            end
                        else
                            h_MIL(1:floor(length(app.t)/2))=app.V;
                            h_MIL(floor(length(app.t)/2)+1:length(app.t))=-app.V;
                            if(app.X(i-1)==0)
                                if(app.Y(i-1)==1)
                                    app.Y(i)=1;
                                else
                                    app.Y(i)=-1;
                                end
                            else
                                if(app.Y(i-1)==1)
                                    app.Y(i)=-1;
                                else
                                    app.Y(i)=1;
                                end
                            end
                        end
                    end
                    temp=kron(app.Y(i),h_MIL);
                    %e(1+(i-1)*(Ts+0.01)/0.01:i*(Ts+0.01)/0.01)=temp;
                    app.e=[app.e temp];
                end
            else
                app.e=kron(app.Y,app.h);
            end
            
            
            %   SNR et inclusion du bruit
            app.Dict_s=app.Dict(length(app.M),app.M);
            app.Eth=app.E_formant(length(app.h),app.h);
            app.Ebit=app.Ebit_average(length(app.M),app.Dict_s,app.Eth);
            % calcul de N0 et 
            N0= app.Ebit/(power(10,app.SNR/10));
           
            Sigma = sqrt(N0/2);
            app.b=transpose(Sigma*randn(length(app.e),1));
            app.e_b=app.e+app.b;
            
           
            %reception
            app.r=app.filtre_recep(app.e_b,app.h,app.scheme,app.V);
            
            %echantillonage
            z=app.echant(app.r,app.scheme);
            
            %desicion
            a=app.bits_recep(z,app.N,app.SchemeDropDown.Value);
            ak=a(1:app.N);
            % Bit error rate
            BER= app.BER_calc(ak,app.N,app.X);
            % PSD
            [ psd f ]= pcov(app.e_b,order,f,fs);
            % output R
            app.EditField_12.Value=ak(1);
            app.EditField_13.Value=ak(2);
            app.EditField_14.Value=ak(3);
            app.EditField_15.Value=ak(4);
            app.EditField_16.Value=ak(5);
            app.EditField_17.Value=ak(6);
            app.EditField_18.Value=ak(7);
            app.EditField_19.Value=ak(8);
            app.EditField_20.Value=ak(9);
            app.EditField_21.Value=ak(10);
            
            app.BEREditField.Value=BER;
            
            % Plots ************************************************
            plot(app.UIAxes,app.Y,'*')
            ylabel(app.UIAxes,'Amplitude (v)'); title(app.UIAxes,'Pulses Y(t)');
            plot(app.UIAxes2,t_,app.e(1:length_t))
            xlabel(app.UIAxes2,'Time (S)');ylabel(app.UIAxes2,'Amplitude (V)'); title(app.UIAxes2,'Emission Signal Shape e(t)');
            plot(app.UIAxes3,t_,app.e_b(1:length_t))
            xlabel(app.UIAxes3,'Time (S)');ylabel(app.UIAxes3,'Amplitude (V)'); title(app.UIAxes3,'Emission Signal Shape e(t)');
            plot(app.UIAxes4,f(1:50*fs),psd(1:50*fs))
            xlabel(app.UIAxes4,'Frequency (Hz)');ylabel(app.UIAxes4,'Amplitude (V)'); title(app.UIAxes4,'Power Spectral density');
            % reception *****************
            plot(app.UIAxes5,t_,app.r(1:length_t))
            xlabel(app.UIAxes5,'Time (S)');ylabel(app.UIAxes5,'Amplitude (V)'); title(app.UIAxes5,'Reception Signal r(t)');
            plot(app.UIAxes6,z,'*')
            xlabel(app.UIAxes6,'Time (S)');ylabel(app.UIAxes6,'Amplitude (V)'); title(app.UIAxes6,'r(t) Sampled');
            plot(app.UIAxes7,ak,'*')
            ylabel(app.UIAxes7,'Value'); title(app.UIAxes7,'Decision');
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.EmissionTab = uitab(app.TabGroup);
            app.EmissionTab.Title = 'Emission';
            app.TabGroup2 = uitabgroup(app.EmissionTab);
            app.NonoiseTab = uitab(app.TabGroup2);
            app.NonoiseTab.Title = 'No noise';
            app.UIAxes = uiaxes(app.NonoiseTab);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.UIAxes2 = uiaxes(app.NonoiseTab);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            app.NoiseTab = uitab(app.TabGroup2);
            app.NoiseTab.Title = 'Noise';
            app.UIAxes4 = uiaxes(app.NoiseTab);
            title(app.UIAxes4, 'Title')
            xlabel(app.UIAxes4, 'X')
            ylabel(app.UIAxes4, 'Y')
            app.UIAxes3 = uiaxes(app.NoiseTab);
            title(app.UIAxes3, 'Title')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            app.ReceptionTab = uitab(app.TabGroup);
            app.ReceptionTab.Title = 'Reception';
            app.UIAxes5 = uiaxes(app.ReceptionTab);
            title(app.UIAxes5, 'Title')
            xlabel(app.UIAxes5, 'X')
            ylabel(app.UIAxes5, 'Y')
            app.UIAxes6 = uiaxes(app.ReceptionTab);
            title(app.UIAxes6, 'Title')
            xlabel(app.UIAxes6, 'X')
            ylabel(app.UIAxes6, 'Y')
            app.UIAxes7 = uiaxes(app.ReceptionTab);
            title(app.UIAxes7, 'Title')
            xlabel(app.UIAxes7, 'X')
            ylabel(app.UIAxes7, 'Y')
            app.EditField = uieditfield(app.UIFigure, 'text');
            app.EditField.Value = 'Baseband modulation';
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Title = 'Panel';
            app.SchemeDropDownLabel = uilabel(app.Panel);
            app.SchemeDropDownLabel.Text = 'Scheme';
            app.SchemeDropDown = uidropdown(app.Panel);
            app.SchemeDropDown.Items = {'None', 'NRZ', 'AMI', 'Manchester', 'Miller', 'MLT3', 'RZ', '2B1Q'};
            app.SchemeDropDown.ValueChangedFcn = createCallbackFcn(app, @SchemeDropDownValueChanged, true);
            app.SchemeDropDown.Value = 'None';
            app.SNREditFieldLabel = uilabel(app.Panel);
            app.SNREditFieldLabel.Text = 'SNR';
            app.SNREditField = uieditfield(app.Panel, 'numeric');
            app.SNREditField.Limits = [0 1000];
            app.SNREditField.Value = 40;
            app.SendPlotButton = uibutton(app.Panel, 'push');
            app.SendPlotButton.ButtonPushedFcn = createCallbackFcn(app, @SendPlotButtonPushed, true);
            app.SendPlotButton.Text = 'Send/Plot';
            app.EditField_1 = uieditfield(app.Panel, 'numeric');
            app.EditField_1.ValueChangedFcn = createCallbackFcn(app, @EditField_1ValueChanged, true);
            app.EditField_1.Limits = [0 1];
            app.EditField_1.ValueDisplayFormat = '%.0f';
            app.EditField_2 = uieditfield(app.Panel, 'numeric');
            app.EditField_3 = uieditfield(app.Panel, 'numeric');
            app.EditField_4 = uieditfield(app.Panel, 'numeric');
            app.EditField_5 = uieditfield(app.Panel, 'numeric');
            app.EditField_6 = uieditfield(app.Panel, 'numeric');
            app.EditField_10 = uieditfield(app.Panel, 'numeric');
            app.EditField_9 = uieditfield(app.Panel, 'numeric');
            app.EditField_8 = uieditfield(app.Panel, 'numeric');
            app.EditField_7 = uieditfield(app.Panel, 'numeric');
            app.CheckBox_1 = uicheckbox(app.Panel);
            app.CheckBox_1.ValueChangedFcn = createCallbackFcn(app, @CheckBox_1ValueChanged, true);
            app.CheckBox_1.Text = '';
            app.CheckBox_2 = uicheckbox(app.Panel);
            app.CheckBox_2.ValueChangedFcn = createCallbackFcn(app, @CheckBox_2ValueChanged, true);
            app.CheckBox_2.Text = '';
            app.CheckBox_3 = uicheckbox(app.Panel);
            app.CheckBox_3.ValueChangedFcn = createCallbackFcn(app, @CheckBox_3ValueChanged, true);
            app.CheckBox_3.Text = '';
            app.CheckBox_4 = uicheckbox(app.Panel);
            app.CheckBox_4.ValueChangedFcn = createCallbackFcn(app, @CheckBox_4ValueChanged, true);
            app.CheckBox_4.Text = '';
            app.CheckBox_5 = uicheckbox(app.Panel);
            app.CheckBox_5.ValueChangedFcn = createCallbackFcn(app, @CheckBox_5ValueChanged, true);
            app.CheckBox_5.Text = '';
            app.CheckBox_6 = uicheckbox(app.Panel);
            app.CheckBox_6.ValueChangedFcn = createCallbackFcn(app, @CheckBox_6ValueChanged, true);
            app.CheckBox_6.Text = '';
            app.CheckBox_7 = uicheckbox(app.Panel);
            app.CheckBox_7.ValueChangedFcn = createCallbackFcn(app, @CheckBox_7ValueChanged, true);
            app.CheckBox_7.Text = '';
            app.CheckBox_8 = uicheckbox(app.Panel);
            app.CheckBox_8.ValueChangedFcn = createCallbackFcn(app, @CheckBox_8ValueChanged, true);
            app.CheckBox_8.Text = '';
            app.EditField_11 = uieditfield(app.Panel, 'text');
            app.EditField_11.Value = 'E';
            app.RandomCheckBox = uicheckbox(app.Panel);
            app.RandomCheckBox.Text = 'Random';
            app.CheckBox_9 = uicheckbox(app.Panel);
            app.CheckBox_9.ValueChangedFcn = createCallbackFcn(app, @CheckBox_9ValueChanged, true);
            app.CheckBox_9.Text = '';
            app.CheckBox_10 = uicheckbox(app.Panel);
            app.CheckBox_10.ValueChangedFcn = createCallbackFcn(app, @CheckBox_10ValueChanged, true);
            app.CheckBox_10.Text = '';
            app.EditField_12 = uieditfield(app.Panel, 'numeric');
            app.EditField_12.Limits = [0 1];
            app.EditField_12.ValueDisplayFormat = '%.0f';
            app.EditField_13 = uieditfield(app.Panel, 'numeric');
            app.EditField_14 = uieditfield(app.Panel, 'numeric');
            app.EditField_15 = uieditfield(app.Panel, 'numeric');
            app.EditField_16 = uieditfield(app.Panel, 'numeric');
            app.EditField_17 = uieditfield(app.Panel, 'numeric');
            app.EditField_18 = uieditfield(app.Panel, 'numeric');
            app.EditField_19 = uieditfield(app.Panel, 'numeric');
            app.EditField_20 = uieditfield(app.Panel, 'numeric');
            app.EditField_21 = uieditfield(app.Panel, 'numeric');
            app.EditField_22 = uieditfield(app.Panel, 'text');
            app.EditField_22.Value = 'R';
            app.BEREditFieldLabel = uilabel(app.Panel);
            app.BEREditFieldLabel.Text = 'BER';
            app.BEREditField = uieditfield(app.Panel, 'numeric');
        end
    end

    methods (Access = public)

        % Construct app
        function app = digcom

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end